classdef DebugApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Line2Gauge_3        matlab.ui.control.LinearGauge
        D21Label            matlab.ui.control.Label
        trainICONexample    matlab.ui.control.Image
        TrainProgressPanel  matlab.ui.container.Panel
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        GridLayout          matlab.ui.container.GridLayout
        SilderD25           matlab.ui.control.LinearGauge
        SilderD24           matlab.ui.control.LinearGauge
        SilderD23           matlab.ui.control.LinearGauge
        SilderD21           matlab.ui.control.LinearGauge
        SilderD26           matlab.ui.control.LinearGauge
        D26Label            matlab.ui.control.Label
        trainICONexample_6  matlab.ui.control.Image
        trainICONexample_5  matlab.ui.control.Image
        D25Label            matlab.ui.control.Label
        SilderD22           matlab.ui.control.LinearGauge
        D22Label            matlab.ui.control.Label
        trainICONexample_4  matlab.ui.control.Image
        D24Label            matlab.ui.control.Label
        D21Label_2          matlab.ui.control.Label
        Tab2                matlab.ui.container.Tab
        TimePanel           matlab.ui.container.Panel
        add5mButton         matlab.ui.control.Button
        add10mButton        matlab.ui.control.Button
        add30mButton        matlab.ui.control.Button
        Label_sysTime       matlab.ui.control.Label
        Label               matlab.ui.control.Label
        trainICONexample_3  matlab.ui.control.Image
        trainICONexample_2  matlab.ui.control.Image
        D23Label            matlab.ui.control.Label
        trainICOND21        matlab.ui.control.Image
        add1hButton         matlab.ui.control.Button
        minus10mButton_2    matlab.ui.control.Button
        MapPanel            matlab.ui.container.Panel
        Line2Gauge_2        matlab.ui.control.LinearGauge
    end

    
    properties (Access = private)
        mapFig; % Description
        gx;
      
    end
    
    properties (Access = public)
        trainDispatch;
        displayedTrains;
    end
    
    methods (Access = public)
        
        function display_update_systime(app)
            app.Label_sysTime.Text=app.trainDispatch.SysTimeDisplay;
        end
        
        function updateTrainUI(app)
            for i = 1:length(app.trainDispatch.Trains)
                train=app.trainDispatch.Trains(i);
                app.getTrainSilder(train).Value=app.computeSliderValue(train);
               
            end

        end
    end
    
    methods (Access = private)
        
        function drawMap(app)

            latshanghaiHQ = 31.19590;
            lonshanghaiHQ = 121.34113;
            
            latnanjingS=32.006161;
            lonnanjingS=118.778572;

            latchangzhouN=31.853495;
            lonchangzhouN=119.955276;

            latsuzhouN=31.421701;
            lonsuzhouN=120.643127;

            lonjiaxingS=120.799957;
            latjiaxingS=30.69058;

            lonliyang=119.4846;
            latliyang=31.41586;

            lonhuzhou=120.023216;
            lathuzhou=30.862961;

            lonhangzhouE=120.212997;
            lathangzhouE=30.29133;

            mapCorx=[latnanjingS latchangzhouN latsuzhouN latshanghaiHQ latjiaxingS lathangzhouE lathuzhou latliyang latnanjingS];
            mapCory=[lonnanjingS lonchangzhouN lonsuzhouN lonshanghaiHQ lonjiaxingS lonhangzhouE lonhuzhou lonliyang lonnanjingS];

            
            geoplot(app.gx,mapCorx, mapCory ,'-pentagram',"LineWidth",1)
            geolimits(app.gx,[30 32.2],[118.4 122])
            %geolimits('auto') 
            geobasemap(app.gx,'topographic')
            
            
        end

        function silder=getTrainSilder(app,train)
            silder=0;
            if strcmp(train.trainCode,"D23")
                silder=app.SilderD23;
            
            elseif strcmp(train.trainCode,"D21")
                silder=app.SilderD21;
            
            elseif strcmp(train.trainCode,"D24")
                silder=app.SilderD24;
            elseif strcmp(train.trainCode,"D22")
                silder=app.SilderD22;
            elseif strcmp(train.trainCode,"D25")
                silder=app.SilderD25;
            elseif strcmp(train.trainCode,"D26")
                silder=app.SilderD26;
            else
                disp("Error! 没法拿到火车的进度条!")
            end
        
            
        end

        function silderValue=computeSliderValue(app,train)
            silderValue=0;
            if strcmp(train.status,"RUNNING")
                
                if train.lineType==1
                    %从哪个站点开始偏移
                    startFrom=0;
                    startStation=train.remainingStations(1).stationName;
                    if strcmp(startStation,"南京南")
                        startFrom=1;
                    end
                    if strcmp(startStation,"溧阳")
                        startFrom=2;
                    end
                    if strcmp(startStation,"湖州")
                        startFrom=3;
                    end
                    if strcmp(startStation,"杭州东")
                        startFrom=4;
                    end
                    if train.lineDirection==1
                        startFrom=5-startFrom;
                    end
                    %计算两个相邻站点之间的百分比
                    
                    startTime=train.remainingStations(1).departureTime;
                    endTime=train.remainingStations(2).arrivalTime;
                    nowTime=app.trainDispatch.SysTime;
                    offset=0;
                    if nowTime>startTime
                        %确保列车没有停靠
                        offset=(nowTime-startTime)/(endTime-startTime);
                    end
                    silderValue=startFrom+offset;
                end
            end
            if strcmp(train.status,"STOP")
                silderValue=4;
            end
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %app.mapFig=uifigure(app.UIFigure);
            %app.gx = geoaxes(app.mapFig);
            app.gx = geoaxes(app.MapPanel);
            drawMap(app);
            %app.display_update_systime();
            keySet = {'Jan','Feb','Mar','Apr'};
            valueSet = [327.2 368.2 197.6 178.4];
            app.displayedTrains = containers.Map(keySet,valueSet)

            %app.displayedTrains = containers.Map

            
        end

        % Button pushed function: add10mButton
        function add10mButtonPushed(app, event)
            "加10分钟时间"
            app.trainDispatch.changeSysTime(minutes(10));
            app.updateTrainUI();
        end

        % Button pushed function: add1hButton
        function add1hButtonPushed(app, event)
            "加1小时时间"
            app.trainDispatch.changeSysTime(hours(1));
            app.updateTrainUI();
        end

        % Button pushed function: minus10mButton_2
        function minus10mButton_2Pushed(app, event)
            "减少10分钟"
            app.trainDispatch.changeSysTime(-minutes(10));
            app.updateTrainUI();
        end

        % Button pushed function: add5mButton
        function add5mButtonPushed(app, event)
            "加5分钟时间"
            app.trainDispatch.changeSysTime(minutes(5));
            app.updateTrainUI();
        end

        % Button pushed function: add30mButton
        function add30mButtonPushed(app, event)
            "加30分钟时间"
            app.trainDispatch.changeSysTime(minutes(30));
            app.updateTrainUI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Line2Gauge_2 = uigauge(app.UIFigure, 'linear');
            app.Line2Gauge_2.Limits = [1 4];
            app.Line2Gauge_2.MajorTicks = [1 2 3 4];
            app.Line2Gauge_2.MajorTickLabels = {'南京南', '溧阳', '湖州', '杭州东'};
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.minus10mButton_2 = uibutton(app.UIFigure, 'push');
            app.minus10mButton_2.ButtonPushedFcn = createCallbackFcn(app, @minus10mButton_2Pushed, true);
            app.minus10mButton_2.Visible = 'off';
            app.minus10mButton_2.Text = {'- 10 min'; ''};
            app.add1hButton = uibutton(app.UIFigure, 'push');
            app.add1hButton.ButtonPushedFcn = createCallbackFcn(app, @add1hButtonPushed, true);
            app.add1hButton.Visible = 'off';
            app.add1hButton.Text = {'+ 1 hour'; ''};
            app.TrainProgressPanel = uipanel(app.UIFigure);
            app.TrainProgressPanel.Title = 'TrainProgress';
            app.TabGroup = uitabgroup(app.TrainProgressPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout = uigridlayout(app.Tab);
            app.trainICOND21 = uiimage(app.GridLayout);
            app.trainICOND21.ImageSource = 'trainIcon.png';
            app.D23Label = uilabel(app.GridLayout);
            app.D23Label.Text = 'D23';
            app.trainICONexample_2 = uiimage(app.GridLayout);
            app.trainICONexample_2.ImageSource = 'trainIcon.png';
            app.trainICONexample_3 = uiimage(app.GridLayout);
            app.trainICONexample_3.ImageSource = 'trainIcon.png';
            app.D21Label_2 = uilabel(app.GridLayout);
            app.D21Label_2.Text = 'D21';
            app.D24Label = uilabel(app.GridLayout);
            app.D24Label.Text = 'D24';
            app.trainICONexample_4 = uiimage(app.GridLayout);
            app.trainICONexample_4.ImageSource = 'trainIcon.png';
            app.D22Label = uilabel(app.GridLayout);
            app.D22Label.Text = 'D22';
            app.SilderD22 = uigauge(app.GridLayout, 'linear');
            app.SilderD22.Limits = [1 4];
            app.SilderD22.MajorTicks = [1 2 3 4];
            app.SilderD22.MajorTickLabels = {'南京南', '溧阳', '湖州', '杭州东'};
            app.D25Label = uilabel(app.GridLayout);
            app.D25Label.Text = {'D25'; ''};
            app.trainICONexample_5 = uiimage(app.GridLayout);
            app.trainICONexample_5.ImageSource = 'trainIcon.png';
            app.trainICONexample_6 = uiimage(app.GridLayout);
            app.trainICONexample_6.ImageSource = 'trainIcon.png';
            app.D26Label = uilabel(app.GridLayout);
            app.D26Label.Text = 'D26';
            app.SilderD26 = uigauge(app.GridLayout, 'linear');
            app.SilderD26.Limits = [1 4];
            app.SilderD26.MajorTicks = [1 2 3 4];
            app.SilderD26.MajorTickLabels = {'南京南', '溧阳', '湖州', '杭州东'};
            app.SilderD21 = uigauge(app.GridLayout, 'linear');
            app.SilderD21.Limits = [1 4];
            app.SilderD21.MajorTicks = [1 2 3 4];
            app.SilderD21.MajorTickLabels = {'杭州东', '湖州', '溧阳', '南京南'};
            app.SilderD23 = uigauge(app.GridLayout, 'linear');
            app.SilderD23.Limits = [1 4];
            app.SilderD23.MajorTicks = [1 2 3 4];
            app.SilderD23.MajorTickLabels = {'杭州东', '湖州', '溧阳', '南京南'};
            app.SilderD24 = uigauge(app.GridLayout, 'linear');
            app.SilderD24.Limits = [1 4];
            app.SilderD24.MajorTicks = [1 2 3 4];
            app.SilderD24.MajorTickLabels = {'南京南', '溧阳', '湖州', '杭州东'};
            app.SilderD25 = uigauge(app.GridLayout, 'linear');
            app.SilderD25.Limits = [1 4];
            app.SilderD25.MajorTicks = [1 2 3 4];
            app.SilderD25.MajorTickLabels = {'杭州东', '湖州', '溧阳', '南京南'};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.trainICONexample = uiimage(app.UIFigure);
            app.trainICONexample.ImageSource = 'trainIcon.png';
            app.D21Label = uilabel(app.UIFigure);
            app.D21Label.Text = 'D21';
            app.Line2Gauge_3 = uigauge(app.UIFigure, 'linear');
            app.Line2Gauge_3.Limits = [1 4];
            app.Line2Gauge_3.MajorTicks = [1 2 3 4];
            app.Line2Gauge_3.MajorTickLabels = {'杭州东', '湖州', '溧阳', '南京南'};
            app.TimePanel = uipanel(app.UIFigure);
            app.TimePanel.Title = 'TimePanel';
            app.Label = uilabel(app.TimePanel);
            app.Label.Text = '系统时间';
            app.Label_sysTime = uilabel(app.TimePanel);
            app.Label_sysTime.Text = {'Label_sysTime'; ''};
            app.add30mButton = uibutton(app.TimePanel, 'push');
            app.add30mButton.ButtonPushedFcn = createCallbackFcn(app, @add30mButtonPushed, true);
            app.add30mButton.Text = {'+ 30 min'; ''};
            app.add10mButton = uibutton(app.TimePanel, 'push');
            app.add10mButton.ButtonPushedFcn = createCallbackFcn(app, @add10mButtonPushed, true);
            app.add10mButton.Text = {'+ 10 min'; ''};
            app.add5mButton = uibutton(app.TimePanel, 'push');
            app.add5mButton.ButtonPushedFcn = createCallbackFcn(app, @add5mButtonPushed, true);
            app.add5mButton.Text = {'+ 5 min'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DebugApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end