classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        LaunchTab               matlab.ui.container.Tab
        LetsbookyourtrainwithjustatapLabel  matlab.ui.control.Label
        BuyTicketsButton        matlab.ui.control.Button
        Image3                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        UserTab                 matlab.ui.container.Tab
        ButtonGroup             matlab.ui.container.ButtonGroup
        Button3                 matlab.ui.control.RadioButton
        Button2                 matlab.ui.control.RadioButton
        Button                  matlab.ui.control.RadioButton
        QueryTab                matlab.ui.container.Tab
        SearchButton            matlab.ui.control.Button
        SelectDestinationPanel  matlab.ui.container.Panel
        ToStationDropDown       matlab.ui.control.DropDown
        FromDropDownLabel_2     matlab.ui.control.Label
        StartStationDropDown    matlab.ui.control.DropDown
        FromDropDownLabel       matlab.ui.control.Label
        Image4                  matlab.ui.control.Image
        SearchedTicketsTab      matlab.ui.container.Tab
        BackBtnInTicket         matlab.ui.control.Button
        ticket1ToTime           matlab.ui.control.Label
        ticket1StartTime        matlab.ui.control.Label
        Button_3                matlab.ui.control.Button
        ticket1Train            matlab.ui.control.Label
        ticket1ToStation        matlab.ui.control.Label
        ticket1StartStation     matlab.ui.control.Label
        Image5                  matlab.ui.control.Image
        BookTab                 matlab.ui.container.Tab
        ticket1ToTime_2         matlab.ui.control.Label
        ticket1StartTime_2      matlab.ui.control.Label
        ticket1Train_2          matlab.ui.control.Label
        ticket1ToStation_2      matlab.ui.control.Label
        ticket1StartStation_2   matlab.ui.control.Label
        BookBtn                 matlab.ui.control.Button
        BackBtnInBook           matlab.ui.control.Button
        Image6                  matlab.ui.control.Image
    end

    
    properties (Access = public)
        trainDispatch % Description
    end
    
    properties (Access = private)
        
    end
    
    methods (Access = public)
        function showTickets(app,tickets)
            if tickets.length==0
                "找不到任何车票！！！"
            end
            if tickets.length>=1
                alltrainCodes="";
                for i=1:tickets(1).length
                    alltrainCodes=alltrainCodes+tickets(1,i).trainCode;
                end

                app.ticket1Train.Text=alltrainCodes;

            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuyTicketsButton
        function BuyTicketsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.QueryTab;
        end

        % Callback function
        function DebugButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.debugTab;
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            startStation=Station(app.StartStationDropDown.Value);
            startStation.arrivalTime=app.trainDispatch.SysTime;
            toStation=Station(app.ToStationDropDown.Value);
            SearchedTickets=app.trainDispatch.findAvailableTickets(startStation,toStation,0);
            "搜索到的车票为"
            disp(SearchedTickets)
            tickets=strsplit(SearchedTickets,"-");
            disp(tickets(1:end-1))
            %app.showTickets(SearchedTickets)
            %app.TabGroup.SelectedTab = app.SearchedTicketsTab;
        end

        % Button pushed function: BackBtnInTicket
        function BackBtnInTicketPushed(app, event)
            app.TabGroup.SelectedTab = app.QueryTab;
        end

        % Button pushed function: BackBtnInBook
        function BackBtnInBookButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SearchedTicketsTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LaunchTab = uitab(app.TabGroup);
            app.LaunchTab.Title = '初始界面';
            app.Image2 = uiimage(app.LaunchTab);
            app.Image2.ImageSource = 'launch-bg-norad.png';
            app.Image3 = uiimage(app.LaunchTab);
            app.Image3.ImageSource = 'launch-bg-gradient-black.png';
            app.BuyTicketsButton = uibutton(app.LaunchTab, 'push');
            app.BuyTicketsButton.ButtonPushedFcn = createCallbackFcn(app, @BuyTicketsButtonPushed, true);
            app.BuyTicketsButton.Text = 'Buy  Tickets';
            app.LetsbookyourtrainwithjustatapLabel = uilabel(app.LaunchTab);
            app.LetsbookyourtrainwithjustatapLabel.Text = {'Let''s book your train'; 'with just a tap'; ''};
            app.UserTab = uitab(app.TabGroup);
            app.UserTab.Title = '用户界面';
            app.ButtonGroup = uibuttongroup(app.UserTab);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.QueryTab = uitab(app.TabGroup);
            app.QueryTab.Title = '查询界面';
            app.Image4 = uiimage(app.QueryTab);
            app.Image4.ImageSource = 'query-bg1.png';
            app.SelectDestinationPanel = uipanel(app.QueryTab);
            app.SelectDestinationPanel.Title = 'Select Destination';
            app.FromDropDownLabel = uilabel(app.SelectDestinationPanel);
            app.FromDropDownLabel.Text = 'From';
            app.StartStationDropDown = uidropdown(app.SelectDestinationPanel);
            app.StartStationDropDown.Items = {'南京南', '常州北', '苏州北', '上海虹桥', '嘉兴南', '溧阳', '湖州', '杭州东'};
            app.StartStationDropDown.Value = '南京南';
            app.FromDropDownLabel_2 = uilabel(app.SelectDestinationPanel);
            app.FromDropDownLabel_2.Text = 'To';
            app.ToStationDropDown = uidropdown(app.SelectDestinationPanel);
            app.ToStationDropDown.Items = {'南京南', '常州北', '苏州北', '上海虹桥', '嘉兴南', '溧阳', '湖州', '杭州东'};
            app.ToStationDropDown.Value = '溧阳';
            app.SearchButton = uibutton(app.QueryTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.SearchedTicketsTab = uitab(app.TabGroup);
            app.SearchedTicketsTab.Title = '车票界面';
            app.Image5 = uiimage(app.SearchedTicketsTab);
            app.Image5.ImageSource = 'ticket-bg-2.png';
            app.ticket1StartStation = uilabel(app.SearchedTicketsTab);
            app.ticket1ToStation = uilabel(app.SearchedTicketsTab);
            app.ticket1Train = uilabel(app.SearchedTicketsTab);
            app.Button_3 = uibutton(app.SearchedTicketsTab, 'push');
            app.Button_3.Text = '订票';
            app.ticket1StartTime = uilabel(app.SearchedTicketsTab);
            app.ticket1StartTime.Text = 'Label2';
            app.ticket1ToTime = uilabel(app.SearchedTicketsTab);
            app.ticket1ToTime.Text = 'Label2';
            app.BackBtnInTicket = uibutton(app.SearchedTicketsTab, 'push');
            app.BackBtnInTicket.ButtonPushedFcn = createCallbackFcn(app, @BackBtnInTicketPushed, true);
            app.BackBtnInTicket.Text = {'返回'; ''};
            app.BookTab = uitab(app.TabGroup);
            app.BookTab.Title = '订票界面';
            app.Image6 = uiimage(app.BookTab);
            app.Image6.ImageSource = 'book-bg-2.png';
            app.BackBtnInBook = uibutton(app.BookTab, 'push');
            app.BackBtnInBook.ButtonPushedFcn = createCallbackFcn(app, @BackBtnInBookButtonPushed, true);
            app.BackBtnInBook.Text = {'返回'; ''};
            app.BookBtn = uibutton(app.BookTab, 'push');
            app.BookBtn.Text = '订票';
            app.ticket1StartStation_2 = uilabel(app.BookTab);
            app.ticket1ToStation_2 = uilabel(app.BookTab);
            app.ticket1Train_2 = uilabel(app.BookTab);
            app.ticket1StartTime_2 = uilabel(app.BookTab);
            app.ticket1StartTime_2.Text = 'Label2';
            app.ticket1ToTime_2 = uilabel(app.BookTab);
            app.ticket1ToTime_2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end